classdef ms_app_checkBrainMasks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        axes1              matlab.ui.control.UIAxes
        G_slider           matlab.ui.control.Slider
        G_text             matlab.ui.control.Label
        Accept_pushbutton  matlab.ui.control.Button
        Load_pushbutton    matlab.ui.control.Button
        OptG_text          matlab.ui.control.Label
        vol_text           matlab.ui.control.Label
        plus_pushbutton    matlab.ui.control.Button
        minus_pushbutton   matlab.ui.control.Button
        selData_text       matlab.ui.control.Label
        DataName_text      matlab.ui.control.Label
        trans_slider       matlab.ui.control.Slider
        text7              matlab.ui.control.Label
        Button_Gplus       matlab.ui.control.Button
        Button_Gminus      matlab.ui.control.Button
        totData_text       matlab.ui.control.Label
    end

    
    methods (Access = private)
        function addColImage(app, V, V2, trans)
            global st
            
            st.vols{1}.mapping='histeq';
            
            % V is the original BrainMask; V2 the difference
            % st.vols{1}.blobs{1}.vol=V;
            % st.vols{1}.blobs{1}.mat=V.mat;
            % st.vols{1}.blobs{1}.max=1;
            % st.vols{1}.blobs{1}.min=0.01;
            % st.vols{1}.blobs{1}.colour = struct('cmap',[0 0 0; 1 0 0],'prop',trans);
            
            % st.vols{1}.blobs{2}.vol=V2;
            % st.vols{1}.blobs{2}.mat=V2.mat;
            % st.vols{1}.blobs{2}.max=1;
            % st.vols{1}.blobs{2}.min=0;
            % st.vols{1}.blobs{2}.colour = struct('cmap',[0 0 0; 1 1 0],'prop',trans);
            
            % st.vols{2}.blobs{1}=st.vols{1}.blobs{2};
            % % st.vols{2}.blobs{1}.colour = struct('cmap',[0 0 0; 1 1 0],'prop',0.3);
            
            % st.vols{2}.blobs{1}.vol=V2;
            % st.vols{2}.blobs{1}.mat=V2.mat;
            % st.vols{2}.blobs{1}.max=1;
            % st.vols{2}.blobs{1}.min=0;
            % st.vols{2}.blobs{1}.colour = [1 1 0];
            
            % for contour
            st.vols{2}.contour.images=1; % to initialise that there should be a contour of image 2 in image 1
            % for d=1:3
            % CData=st.vols{2}.ax{d}.d.CData;
            % CData(CData>0)=1; st.vols{2}.ax{d}.d.CData=CData;
            % end
            
            hM = findobj(st.vols{2}.ax{1}.cm,'Label','Contour');
            hM.UserData.nblines=1;
            spm_ov_contour('redraw',2,1)
            % delete(hM)
            % contour_display(2,NaN)
            % force a "reposition"
            spm_orthviews('reposition');
        end
        
        function contour_delete(app, i)
            
            global st
            
            try, delete(cat(1,st.vols{i}.contour.handles{:})); end
        end
        
        function contour_display(app, i, o)
            
            global st
            
            if nargin < 2
                o = spm_input('Select image(s)', '!+1', 'e', ...
                    num2str(spm_orthviews('valid_handles')));
                o = intersect(spm_orthviews('valid_handles'),o);
            elseif isinf(o)
                o = spm_orthviews('valid_handles');
            elseif isnan(o)
                o = setxor(spm_orthviews('valid_handles'),i);
            end
            
            % try
            %     hM = findobj(st.vols{i}.ax{1}.cm,'Label','Contour');
            %     UD = get(hM,'UserData');
            %     nblines = UD.nblines;
            %     linestyle = UD.style;
            % catch
            %     nblines = 3;
            %     linestyle = 'r-';
            % end
            linewidth = 1;
            nblines = 1;
            linestyle = 'r-';
            contour_delete(app, i);
            
            lh = {};
            sw = warning('off','MATLAB:contour:ConstantData');
            for d = 1:3
                CData = sqrt(sum(get(st.vols{i}.ax{d}.d,'CData').^2, 3));
                CData(isinf(CData)) = NaN;
                CData(isnan(CData)) = 0;
                CData(CData>0) = 1;
                for h = o(:)'
                    set(st.vols{h}.ax{d}.ax,'NextPlot','add');
                    [C,lh{end+1}] = ...
                        contour(st.vols{h}.ax{d}.ax,CData,...
                        nblines,linestyle,'LineWidth',linewidth);
                end
            end
            warning(sw);
            set(cat(1,lh{:}),'HitTest','off');
            
            st.vols{i}.contour.images = o;
            st.vols{i}.contour.handles = lh;
        end
        
        function contour_redraw(app, i, varargin)
            
            global st
            
            contour_delete(app, i);
            contour_display(app, i,st.vols{i}.contour.images);
        end
        
        function plotBrainMask(app, handles)
            Mtx=handles.Mtx;
            G = str2double(handles.G_text.String); % get the selected G
            I_border = handles.I_border;
            
            maskBrain=zeros(size(Mtx));
            if iscell(I_border)
                for n=1:size(Mtx,3)
                    maskBrain(:,:,n)=I_border{G}{n};
                end
            else
                maskBrain = squeeze(I_border(G,:,:,:));
            end
            handles.vol_text.String = sprintf('Brain volume: %.2f mm^3\n', length(find(maskBrain))*prod(handles.voxdim)/1000);
            % plot the GI
            delete(findobj(handles.axes1,'Tag','G'))
            plot(handles.axes1,G,handles.G_I(G),'ro', 'Tag','G');
            
            % use spm_check_reg
            [d,name,ext]=fileparts(handles.BC);
            Vtmp=spm_vol(handles.BC);
            if length(Vtmp)>1; Vtmp=spm_vol([handles.BC ',1']); end
            Vtmp.fname = [d filesep 'brainTMP' ext];
            % spm_write_vol(Vtmp, Mtx.*maskBrain);
            spm_write_vol(Vtmp, maskBrain);
            % add the difference compared to the found G
            maskBrainF=zeros(size(Mtx));
            if iscell(I_border)
                for n=1:size(Mtx,3)
                    maskBrainF(:,:,n)=I_border{handles.optG}{n};
                end
            else
                maskBrainF = squeeze(I_border(handles.optG,:,:,:));
            end
            diffMask = abs(maskBrain-maskBrainF); diffMask(diffMask==0)=NaN;
            Vtmp2=Vtmp; Vtmp2.fname = [d filesep 'brainTMP2' ext];
            spm_write_vol(Vtmp2, Mtx.*diffMask);
            % that is a pain in the a**!!!!
            % toWrite(:,:,:,1)=maskBrain;
            % toWrite(:,:,:,2)=diffMask;
            % % Vtmp.dim=[Vtmp.dim size(toWrite,4)];
            % for ix=1:size(toWrite,4)
            %     Vtmp(ix)=Vtmp(1);
            %     Vtmp(ix).private.dat.dim=size(toWrite);
            %     Vtmp(ix).private.dat=toWrite;
            %     spm_write_vol(Vtmp(ix), toWrite(:,:,:,ix));
            % end
            
            % show something
            % global st; xhair=st.centre;
            if ~handles.spmCheckDone || isempty(findobj(0,'type','figure','tag', 'Graphics'))
            %     spm_check_registration(char([handles.BC ',1'], Vtmp.fname),{'Red: curr. sel. mask; yell: diff', 'yell: difference'});
                spm_check_registration(char([handles.BC ',1'], Vtmp.fname));
            end
            % spm_check_registration(char(handles.BC))
            addColImage(app, Vtmp,Vtmp2, handles.trans_slider.Value);
        end
        
        function handles = preparePlotting(app, handles)
            fprintf('Loading %s \n', handles.P{str2double(handles.selData_text.String)})
            load(handles.P{str2double(handles.selData_text.String)}); % load the corresponding BrainMask .mat file
            handles.BC=BC;
            [~,name,~]=fileparts(BC); handles.DataName_text.String=name;
            handles.I_border=I_border;
            handles.optG=optG;
            delete(handles.axes1.Children)
            idx=find(G_I>0,1,'last'); if idx~=length(G_I); endOfGI= idx-1; else; endOfGI=idx; end
            G_I = G_I(1:endOfGI)/1000; handles.G_I = G_I;
            plot(G_I,'b', 'Parent', handles.axes1); plot(handles.axes1,optG,handles.G_I(optG),'rx', 'Tag','optG', 'MarkerSize',15); % show the optimal G found by algorithm
            
            handles.G_slider.Min=1; handles.G_slider.Max=length(G_I); 
            handles.G_slider.SliderStep = [1/(handles.G_slider.Max-1) , 1/(handles.G_slider.Max-1) ];
            handles.G_slider.Value=optG;
            handles.G_text.String=num2str(optG);
            if(exist('userG', 'var')) % in case there was already a user choice made!
                plot(handles.axes1,userG,handles.G_I(userG),'bx', 'Tag','userG', 'MarkerSize',15);
                handles.G_slider.Value=userG;
                handles.G_text.String=num2str(userG);
            end
            handles.Mtx=spm_read_vols(spm_vol([BC ',1']));
            handles.OptG_text.String = sprintf('OptG: %.0f',optG);
            if(exist('userG', 'var')); handles.OptG_text.String = sprintf('OptG: %.0f; prev. chosen G by user: %.0f',optG,userG); end
            V=spm_vol(BC); V=V(1); voxdim=spm_imatrix(V.mat); handles.voxdim=abs(voxdim(7:9));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ms_gui_checkBrainMasks_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ms_gui_checkBrainMasks (see VARARGIN)
            
            % Choose default command line output for ms_gui_checkBrainMasks
            handles.output = hObject;
            handles.spmCheckDone=0;
            % we need spm12!!
            if isempty(strfind(which('spm'),'spm12'));
                uiwait(warndlg('ms_gui_checkBrainMasks needs spm12! I will switch..', 'Switch to spm12'));
                spm12p;
            end
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Accept_pushbutton
        function Accept_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Accept_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Mtx=handles.Mtx;
            G = str2double(handles.G_text.String); % chosen G by user
            I_border = handles.I_border;
            
            maskBrain=zeros(size(Mtx));
            if iscell(I_border)
                for n=1:size(Mtx,3)
                    maskBrain(:,:,n)=I_border{G}{n};
                end
            else
                maskBrain = squeeze(I_border(G,:,:,:));
            end
            [d,name,ext]=fileparts(handles.BC);
            Vtmp=spm_vol(handles.BC);
            Vtmp.fname = [d filesep name '_brain' ext];
            fprintf('writing %s\n', Vtmp.fname)
            spm_write_vol(Vtmp, Mtx.*maskBrain);
            Vtmp.fname = [d filesep name '_brainmask' ext];
            fprintf('writing %s\n', Vtmp.fname)
            spm_write_vol(Vtmp, maskBrain);
            fprintf(' done\n');
            %% add the chosen G by user to the mat file
            userG = G;
            matFile = handles.P{str2num(app.selData_text.Text)}; % the corresponding mat file
            save(matFile, 'userG', '-append') % append (!) it
        end

        % Value changed function: G_slider
        function G_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to G_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            handles.G_text.String=num2str(round(handles.G_slider.Value));
            plotBrainMask(app, handles);
        end

        % Button pushed function: Load_pushbutton
        function Load_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Load_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            P=spm_select(Inf,'mat','Select BrainMasks mat-files',[],pwd,'BrainMasks*');
            handles.P=cellstr(P);
            handles.selData_text.String='1';
            app.totData_text.Text = ['/' num2str(length(handles.P))]
            handles=preparePlotting(app, handles);
            
            handles.spmCheckDone=0;
            plotBrainMask(app, handles);
            handles.spmCheckDone=1;
            guidata(hObject, handles);
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isfield(handles, 'BC')
                [d,name,ext]=fileparts(handles.BC);
                delete([d filesep 'brainTMP' ext]);
                delete([d filesep 'brainTMP2' ext]);
            end
            % Hint: delete(hObject) closes the figure
            delete(hObject);
        end

        % Button pushed function: minus_pushbutton
        function minus_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to minus_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isfield(handles, 'BC')
                [d,name,ext]=fileparts(handles.BC);
                delete([d filesep 'brainTMP' ext]);
                delete([d filesep 'brainTMP2' ext]);
            end
            newNum=str2double(handles.selData_text.String)-1;
            if(newNum==0); return; end
            handles.selData_text.String=num2str(newNum);
            handles=preparePlotting(app, handles);
            handles.spmCheckDone=0;
            plotBrainMask(app, handles);
            handles.spmCheckDone=1;
            guidata(hObject, handles);
        end

        % Button pushed function: plus_pushbutton
        function plus_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to plus_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isfield(handles, 'BC')
                [d,name,ext]=fileparts(handles.BC);
                delete([d filesep 'brainTMP' ext]);
                delete([d filesep 'brainTMP2' ext]);
            end
            newNum=str2double(handles.selData_text.String)+1;
            if(newNum > length(handles.P)); return; end
            handles.selData_text.String=num2str(newNum);
            handles=preparePlotting(app, handles);
            handles.spmCheckDone=0;
            plotBrainMask(app, handles);
            handles.spmCheckDone=1;
            guidata(hObject, handles);
        end

        % Value changed function: trans_slider
        function trans_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to trans_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global st
            trans = get(hObject,'Value');
            st.vols{1}.blobs{1}.colour = struct('cmap',[0 0 0; 1 0 0],'prop',trans);
            st.vols{1}.blobs{2}.colour = struct('cmap',[0 0 0; 1 1 0],'prop',trans);
            spm_orthviews('reposition');
        end

        % Button pushed function: Button_Gplus
        function Button_GplusPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            try
                app.G_slider.Value = app.G_slider.Value +1;
                app.G_text.Text=num2str(round(app.G_slider.Value));
                plotBrainMask(app, handles);
            catch
            end
        end

        % Button pushed function: Button_Gminus
        function Button_GminusPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            try
                app.G_slider.Value = app.G_slider.Value - 1;
                app.G_text.Text=num2str(round(app.G_slider.Value));
                plotBrainMask(app, handles);
            catch
            end
        end

        % Value changing function: G_slider
        function G_sliderValueChanging(app, event)
            changingValue = event.Value;
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            app.G_text.Text=num2str(round(app.G_slider.Value));
            plotBrainMask(app, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ms_gui_checkBrainMasks';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            title(app.axes1, 'Brain Mask Evolution')
            xlabel(app.axes1, 'Iterations')
            app.axes1.Tag = 'axes1';
            app.G_slider = uislider(app.figure1);
            app.G_slider.Limits = [0 1];
            app.G_slider.MajorTicks = [];
            app.G_slider.ValueChangedFcn = createCallbackFcn(app, @G_slider_Callback, true);
            app.G_slider.ValueChangingFcn = createCallbackFcn(app, @G_sliderValueChanging, true);
            app.G_slider.MinorTicks = [];
            app.G_slider.Tag = 'G_slider';
            app.G_text = uilabel(app.figure1);
            app.G_text.Tag = 'G_text';
            app.G_text.Text = '';
            app.Accept_pushbutton = uibutton(app.figure1, 'push');
            app.Accept_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @Accept_pushbutton_Callback, true);
            app.Accept_pushbutton.Tag = 'Accept_pushbutton';
            app.Accept_pushbutton.Text = 'Accept';
            app.Load_pushbutton = uibutton(app.figure1, 'push');
            app.Load_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @Load_pushbutton_Callback, true);
            app.Load_pushbutton.Tag = 'Load_pushbutton';
            app.Load_pushbutton.Text = 'Load';
            app.OptG_text = uilabel(app.figure1);
            app.OptG_text.Tag = 'OptG_text';
            app.OptG_text.Text = '';
            app.vol_text = uilabel(app.figure1);
            app.vol_text.Tag = 'vol_text';
            app.vol_text.Text = 'Brain Volume';
            app.plus_pushbutton = uibutton(app.figure1, 'push');
            app.plus_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @plus_pushbutton_Callback, true);
            app.plus_pushbutton.Tag = 'plus_pushbutton';
            app.plus_pushbutton.Text = '+';
            app.minus_pushbutton = uibutton(app.figure1, 'push');
            app.minus_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @minus_pushbutton_Callback, true);
            app.minus_pushbutton.Tag = 'minus_pushbutton';
            app.minus_pushbutton.Text = '-';
            app.selData_text = uilabel(app.figure1);
            app.selData_text.Tag = 'selData_text';
            app.selData_text.Text = '';
            app.DataName_text = uilabel(app.figure1);
            app.DataName_text.Tag = 'DataName_text';
            app.DataName_text.Text = '';
            app.trans_slider = uislider(app.figure1);
            app.trans_slider.Limits = [0 1];
            app.trans_slider.MajorTicks = [];
            app.trans_slider.ValueChangedFcn = createCallbackFcn(app, @trans_slider_Callback, true);
            app.trans_slider.MinorTicks = [];
            app.trans_slider.Tag = 'trans_slider';
            app.trans_slider.Enable = 'off';
            app.trans_slider.Visible = 'off';
            app.trans_slider.Value = 0.3;
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Visible = 'off';
            app.text7.Text = 'Transparency';
            app.Button_Gplus = uibutton(app.figure1, 'push');
            app.Button_Gplus.ButtonPushedFcn = createCallbackFcn(app, @Button_GplusPushed, true);
            app.Button_Gplus.Text = '+';
            app.Button_Gminus = uibutton(app.figure1, 'push');
            app.Button_Gminus.ButtonPushedFcn = createCallbackFcn(app, @Button_GminusPushed, true);
            app.Button_Gminus.Text = '-';
            app.totData_text = uilabel(app.figure1);
            app.totData_text.Tag = 'selData_text';
            app.totData_text.Text = '/';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ms_app_checkBrainMasks(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)ms_gui_checkBrainMasks_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end